classdef MBD_CICD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        S01_Panel_5                   matlab.ui.container.Panel
        S01_BT_OpnSourceTree          matlab.ui.control.Button
        GitVersionControlSystemLabel  matlab.ui.control.Label
        S01_Panel_6                   matlab.ui.container.Panel
        S01_BT_CcsOpen                matlab.ui.control.Button
        ConsoleLabel                  matlab.ui.control.Label
        S01_TA_Build                  matlab.ui.control.TextArea
        TextAreaLabel                 matlab.ui.control.Label
        S01_BT_BuildPrj               matlab.ui.control.Button
        BuildProjectCCS1281Label      matlab.ui.control.Label
        S01_Panel_4                   matlab.ui.container.Panel
        S01_BT_UpdataInterface        matlab.ui.control.Button
        S01_BT_LoadInterface          matlab.ui.control.Button
        S01_LB_InterfaceSet           matlab.ui.control.Label
        InterfaceSettingsLabel        matlab.ui.control.Label
        S01_Panel_3                   matlab.ui.container.Panel
        S01_BT_SimSimMdl              matlab.ui.control.Button
        S01_BT_SimOpnMdl              matlab.ui.control.Button
        S01_BT_SimParamSet            matlab.ui.control.Button
        S01_LB_SimSetParam            matlab.ui.control.Label
        SimulationParameterSettingsLabel  matlab.ui.control.Label
        AutoCodeGenerationLabel       matlab.ui.control.Label
        S01_Panel_2                   matlab.ui.container.Panel
        S01_BT_OpnSlx                 matlab.ui.control.Button
        S01_BT_CompareCode1           matlab.ui.control.Button
        SelectSWCLabel                matlab.ui.control.Label
        S01_TreeSelCode               matlab.ui.container.CheckBoxTree
        SelectSWCNode                 matlab.ui.container.TreeNode
        b01_OSnSchedulerNode          matlab.ui.container.TreeNode
        c01_MainAdcNode               matlab.ui.container.TreeNode
        c02_SubAdcNode                matlab.ui.container.TreeNode
        d01_DiaglntegNode             matlab.ui.container.TreeNode
        e01_MtrCtrl_VarGainNode       matlab.ui.container.TreeNode
        e02_MtrCtrl_PstNode           matlab.ui.container.TreeNode
        e03_MtrCtrl_MtrCtrlNode       matlab.ui.container.TreeNode
        e04_MtrCtrl_PwmSetNode        matlab.ui.container.TreeNode
        f01_CommRx1Node               matlab.ui.container.TreeNode
        g03_CommTxNode                matlab.ui.container.TreeNode
        h01_MdeMgmtNode               matlab.ui.container.TreeNode
        j01_McuInitNode               matlab.ui.container.TreeNode
        Label                         matlab.ui.control.Label
        S01_LB_GenCode                matlab.ui.control.Label
        S01_BT_GenCod                 matlab.ui.control.Button
        InterfaceandParameterSettingsLabel  matlab.ui.control.Label
        S01_Panel_1                   matlab.ui.container.Panel
        S01_BT_ParamSet               matlab.ui.control.Button
        S01_BT_ImportDBFile           matlab.ui.control.Button
        S01_LB_SetParam               matlab.ui.control.Label
        LB_GuideText_2                matlab.ui.control.Label
        SWCICDAppLabel                matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % assignin('base','ST', ST);
    % load('aMain_VehNr_01.mat');
    % run('Step08_Plot');
    % writetable(app.GVS01_EditElecMagntDefine, GMS01.SaveFilePathName,'Sheet', 'Sheet01_ElecMagntDefine', 'Range', 'A1','PreserveFormat',true);

    properties (Access = private)
        S01_FileName
        S01_Path
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: S01_BT_GenCod
        function S01_BT_GenCodButtonPushed(app, event)
            %% mlx 실행 오류 방지를 위한 변수 설정 (mlx실행 시 오류가 없으나, App으로 실한한 경우 하기 선언이 없다는 오류 발생)
            OutFile = 'ExportInterface.xlsx';
            InFile = 'SWC_Info.xlsx';

            %% Visible 설정
            app.S01_LB_GenCode.Visible = "on";

            [Row Column] = size(app.S01_TreeSelCode.CheckedNodes);

            if(Row == 0)
                app.S01_LB_GenCode.Text = 'SWC 가 선택되지 않았습니다.';
                msgbox("SWC 가 선택되지 않았습니다.");
            else
                % Check Tree 항목 확인 및 코드 생성
                app.S01_LB_GenCode.Text = '코드 생성 중...';

                % progressbar 초기 설정
                progressbar('Generating Code');
                drawnow;
                progressbar(0); 
                GenCodePrcsbr.n = 0;
                GenCodePrcsbr.g = 100 / Row;
    
                for i=1:Row
                    try
                        MdlName = app.S01_TreeSelCode.CheckedNodes(i).Text;  SubCodeGen;
                        GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;
                        progressbar(GenCodePrcsbr.n * 0.01);
                    catch
                        % Select SWC에서 All을 선택할 경우 최상위Select SWC는 코드 생성 제외
                        GenCodePrcsbr.g = 100 / (Row-1);
                    end
                end
    
                % run('a01_Main_GenCode');
    
                clear GenCodePrcsbr;
                progressbar(); clear GenCodePrcsbr;
                app.S01_LB_GenCode.Text = '코드 생성 완료';
                msgbox("코드 생성이 완료되었습니다.");
            end
        end

        % Clicked callback: S01_TreeSelCode
        function S01_TreeSelCodeClicked(app, event)
            node = event.InteractionInformation.Node;
            app.S01_TreeSelCode.expand;

            app.S01_LB_GenCode.Visible = "on";
            app.S01_LB_GenCode.Text = '생성할 코드를 선택하세요.';
        end

        % Button pushed function: S01_BT_ImportDBFile
        function S01_BT_ImportDBFileButtonPushed(app, event)
            % try
            %     % 파일명 Load
            %     mDefine_local = evalin('base', 'mDefine');
            % 
            %     % 엑셀 파일 Open
            %     winopen(mDefine_local.Excel.FileName);
            % catch
                % 파일 선택
                [app.S01_FileName, app.S01_Path] = uigetfile({'*.xlsx'},'Select a file','MBD\Sources\CtrlParam');

                % 엑셀 파일 Open
                if(app.S01_FileName == 0)                
                    return; % 함수 종료       
                end

                % 엑셀 파일 Open
                winopen(app.S01_FileName);
            % end         

            % Text Display
            app.S01_LB_SetParam.Visible = "on";
            app.S01_LB_SetParam.Text = 'Data를 수정한 후 저장하십시오.';
        end

        % Button pushed function: S01_BT_ParamSet
        function S01_BT_ParamSetButtonPushed(app, event)
            % Matlab base에서 mlx 파일 실행
            % Text Display
            app.S01_LB_SetParam.Visible = "on";
            app.S01_LB_SetParam.Text = {'엑셀 파일을 선택하고', '약 20초 동안 대기하십시오.'};

            evalin('base', 'run("a01_Main.mlx")');

            % Text Display
            app.S01_LB_SetParam.Text = 'Data를 작업공간에 저장하였습니다.';   
        end

        % Button pushed function: S01_BT_LoadInterface
        function S01_BT_LoadInterfaceButtonPushed(app, event)
            %% Visible 설정
            app.S01_LB_InterfaceSet.Visible = "on";
            app.S01_LB_InterfaceSet.Text = 'Data를 수정한 후 저장하십시오.';

            % 엑셀 파일 Open
            winopen('ExportInterface.xlsx');
        end

        % Button pushed function: S01_BT_UpdataInterface
        function S01_BT_UpdataInterfaceButtonPushed(app, event)
            %% Visible 설정
            app.S01_LB_InterfaceSet.Visible = "on";
            app.S01_LB_InterfaceSet.Text = 'Interface 업데이트 중...';

            InFile = 'ExportInterface.xlsx';

            % progressbar 초기 설정
            progressbar('Update Interface');
            drawnow;
            progressbar(0); 
            GenCodePrcsbr.n = 0;
            GenCodePrcsbr.g = 100 / 12;         % 모델 개수 12

            MdlName = 'b01_OSnScheduler';       Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'c01_MainAdc';            Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'c02_SubAdc';             Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'd01_Diaglnteg';          Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'e01_MtrCtrl_VarGain';    Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'e02_MtrCtrl_Pst';        Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'e03_MtrCtrl_MtrCtrl';    Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'e04_MtrCtrl_PwmSet';     Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'f01_CommRx1';            Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'g03_CommTx';             Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            % MdlName = 'h01_MdeMgmt';            Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'j01_McuInit';            Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);

            progressbar(); clear GenCodePrcsbr;
            app.S01_LB_InterfaceSet.Text = 'Interface 업데이트 완료';
            msgbox("Interface 업데이트가 완료되었습니다.");

            % evalin('base', 'run("a03_Sub_UpdateInterface.mlx")');
        end

        % Button pushed function: S01_BT_SimParamSet
        function S01_BT_SimParamSetButtonPushed(app, event)
            evalin('base', 'run("a02_SimVariable.mlx")');
            app.S01_LB_SimSetParam.Text = {'시뮬레이션 Parameter 설정이', '완료되었습니다.'};
            % msgbox("시뮬레이션 Parameter 설정이 완료되었습니다.");
        end

        % Button pushed function: S01_BT_CompareCode1
        function S01_BT_CompareCode1ButtonPushed(app, event)
            try
                system('"C:\Program Files\Beyond Compare 5\BCompare.exe" MBD\Codegen T220_2P0_020_C0P0_TI28346\ASW');
            catch
                system('"C:\Program Files\Beyond Compare 4\BCompare.exe" MBD\Codegen T220_2P0_020_C0P0_TI28346\ASW');
                try
                    system('"C:\Program Files\WinMerge\WinMergeU.exe" MBD\Codegen T220_2P0_020_C0P0_TI28346\ASW');
                catch
                    msgbox("Beyond Compare 또는 WinMerge를 설치해야 합니다.");
                end                
            end
        end

        % Button pushed function: S01_BT_SimOpnMdl
        function S01_BT_SimOpnMdlButtonPushed(app, event)
            open('a01_SimTurret.slx');
        end

        % Button pushed function: S01_BT_SimSimMdl
        function S01_BT_SimSimMdlButtonPushed(app, event)
            sim('a01_SimTurret.slx');
        end

        % Button pushed function: S01_BT_OpnSourceTree
        function S01_BT_OpnSourceTreeButtonPushed(app, event)
            % sourcetreePath
            sourcetreePath = '"C:\Users\admin\AppData\Local\SourceTree\SourceTree.exe"';
            
            % 실행
            [status, result] = system(sourcetreePath);
        end

        % Button pushed function: S01_BT_OpnSlx
        function S01_BT_OpnSlxButtonPushed(app, event)
            [Row Column] = size(app.S01_TreeSelCode.CheckedNodes);

            if(Row == 0)
                app.S01_LB_GenCode.Text = 'SWC 가 선택되지 않았습니다.';
                msgbox("SWC 가 선택되지 않았습니다.");
            else
                 for i=1:Row
                    try
                        tmp = app.S01_TreeSelCode.CheckedNodes(i).Text;
                        tmp = strcat(tmp,'.slx');
                        open(tmp);
                    catch
                        msgbox("선택된 SWC 찾을 수 없습니다.");                     
                    end
                 end
            end

        end

        % Button pushed function: S01_BT_BuildPrj
        function S01_BT_BuildPrjButtonPushed(app, event)
            % % Dubug 폴더 삭제
            % app.S01_TA_Build.Value = '기존에 생성된 Dubug 폴더를 삭제하고 빌드를 시작합니다. Building...';
            % drawnow;
            % 
            % try
            %     rmdir T220_2P0_020_C0P0_TI28346\Debug s;
            % catch
            % 
            % end
            % 
            % % CCS 빌드 명령어 준비
            % proj = currentProject;
            % workspace = proj.RootFolder; % 백슬래시 유지
            % eclipsec = 'C:\ti\ccs1281\ccs\eclipse\eclipsec.exe';
            % project = 'T220_2P0_020_C0P0_TI28346';
            % 
            % lockFilePath = fullfile(workspace, '.metadata', '.lock');
            % 
            % % .lock 파일 존재하면 삭제
            % if isfile(lockFilePath)
            %     fprintf('🔒 .lock 파일을 발견했습니다. 삭제합니다: %s\n', lockFilePath);
            %     delete(lockFilePath);
            %     pause(0.5); % 잠시 대기
            % end
            % 
            % buildCommand = sprintf('"%s" -noSplash -data "%s" -application com.ti.ccstudio.apps.projectBuild -ccs.projects %s', ...
            %     eclipsec, workspace, project);
            % 
            % % 명령어를 스페이스로 쪼갬
            % cmdParts = split(buildCommand);
            % 
            % % Java ProcessBuilder 생성
            % pb = java.lang.ProcessBuilder(cmdParts);
            % pb.redirectErrorStream(true); % 표준출력 + 표준에러 합치기
            % process = pb.start();
            % 
            % % 출력 스트림 읽기 준비
            % inputStream = process.getInputStream();
            % inputStreamReader = java.io.InputStreamReader(inputStream);
            % bufferedReader = java.io.BufferedReader(inputStreamReader);
            % 
            % % TextArea 초기화
            % app.S01_TA_Build.Value = {'빌드 시작 중...'};
            % 
            % % 한 줄씩 읽어서 표시
            % line = bufferedReader.readLine();
            % while ischar(line) || ~isempty(line)
            %     if isempty(line)
            %         pause(0.01); % 잠깐 쉬고 다시
            %         line = bufferedReader.readLine();
            %         continue;
            %     end
            % 
            %     % 기존 TextArea에 추가
            %     app.S01_TA_Build.Value = [app.S01_TA_Build.Value; char(line)];
            %     drawnow; % GUI 업데이트
            % 
            %     % 다음 줄 읽기
            %     line = bufferedReader.readLine();
            % end
            % 
            % % 프로세스 종료 대기
            % process.waitFor();
            % 
            % % 빌드 종료 상태 확인
            % exitCode = process.exitValue();
            % if exitCode == 0
            %     app.S01_TA_Build.Value = [app.S01_TA_Build.Value; '✅ 빌드 성공!'];
            %     msgbox("✅ 빌드 성공!");                
            % else
            %     % 기존 TextArea에 출력
            %     failMessage = sprintf('❌ 빌드 실패! (Exit Code: %d)', exitCode);
            %     app.S01_TA_Build.Value = [app.S01_TA_Build.Value; failMessage];
            % 
            %     % 팝업창으로도 출력
            %     msgbox(failMessage, '빌드 실패', 'error');
            % end

    % Dubug 폴더 삭제
    app.S01_TA_Build.Value = '기존에 생성된 Debug 폴더를 삭제하고 빌드를 시작합니다. Building...';
    drawnow;

    try
        rmdir T220_2P0_020_C0P0_TI28346\Debug s;
    catch
        % 삭제 실패해도 무시
    end

    % CCS 빌드 명령어 준비
    proj = currentProject;
    workspace = proj.RootFolder; % 백슬래시 유지
    eclipsec = 'C:\ti\ccs1281\ccs\eclipse\eclipsec.exe';
    project = 'T220_2P0_020_C0P0_TI28346';

    lockFilePath = fullfile(workspace, '.metadata', '.lock');

    % .lock 파일 존재하면 삭제
    if isfile(lockFilePath)
        fprintf('🔒 .lock 파일을 발견했습니다. 삭제합니다: %s\n', lockFilePath);
        delete(lockFilePath);
        pause(0.5); % 잠시 대기
    end

    buildCommand = sprintf('"%s" -noSplash -data "%s" -application com.ti.ccstudio.apps.projectBuild -ccs.projects %s', ...
        eclipsec, workspace, project);

    cmdParts = split(buildCommand);

    % Java ProcessBuilder 생성
    pb = java.lang.ProcessBuilder(cmdParts);
    pb.redirectErrorStream(true); % 표준출력 + 표준에러 합치기
    process = pb.start();

    inputStream = process.getInputStream();
    inputStreamReader = java.io.InputStreamReader(inputStream);
    bufferedReader = java.io.BufferedReader(inputStreamReader);

    % TextArea 초기화
    app.S01_TA_Build.Value = {'빌드 시작 중...'};
    drawnow;

    % progressbar 초기 설정
    progressbar('CCS Building...');
    drawnow;
    progressbar(0); 

    % 진행률 관련 변수
    totalLinesEstimate = 200; % 예측 로그 줄 수 (빌드 로그 많으면 증가)
    currentLineCount = 0;

    % 한 줄씩 읽어서 표시
    line = bufferedReader.readLine();
    while ischar(line) || ~isempty(line)
        if isempty(line)
            pause(0.01);
            line = bufferedReader.readLine();
            continue;
        end

        app.S01_TA_Build.Value = [app.S01_TA_Build.Value; char(line)];
        drawnow;

        % progressbar 업데이트
        currentLineCount = currentLineCount + 1;
        progress = min(currentLineCount / totalLinesEstimate, 1); % 1 넘지 않게
        progressbar(progress);

        line = bufferedReader.readLine();
    end

    % 프로세스 종료 대기
    process.waitFor();

    % 빌드 종료 상태 확인
    exitCode = process.exitValue();
    if exitCode == 0
        app.S01_TA_Build.Value = [app.S01_TA_Build.Value; '✅ 빌드 성공!'];
        drawnow;
        msgbox("✅ 빌드 성공!");
    else
        failMessage = sprintf('❌ 빌드 실패! (Exit Code: %d)', exitCode);
        app.S01_TA_Build.Value = [app.S01_TA_Build.Value; failMessage];
        drawnow;
        msgbox(failMessage, '빌드 실패', 'error');
    end

    % progressbar 닫기
    progressbar(1); % 100% 완료
    pause(0.2); % 잠깐 보여주고
    progressbar(); % progressbar 종료
        end

        % Button pushed function: S01_BT_CcsOpen
        function S01_BT_CcsOpenButtonPushed(app, event)
            % CCS Open 명령어 준비
            % eclipsec = '"C:\ti\ccs1281\ccs\eclipse\eclipsec.exe"';
            % workspace = sprintf('"%s"', char(currentProject().RootFolder));
            % project = 'T220_2P0_020_C0P0_TI28346';
            % 
            % % Open 명령어 조합
            % openCommand = sprintf('%s -noSplash -data %s', eclipsec, workspace);
            % 
            % % 비동기 실행
            % system(['start "" ' openCommand]);

            eclipsec = '"C:\ti\ccs1281\ccs\eclipse\eclipsec.exe"';
            workspace = sprintf('"%s"', char(currentProject().RootFolder));
            openCommand = sprintf('%s -noSplash -data %s', eclipsec, workspace);
            
            % 콘솔창 없이 CCS 실행
            system(['start /B "" ' openCommand ' > NUL 2>&1']);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.SWCICDAppLabel = uilabel(app.Panel);
            app.SWCICDAppLabel.Text = '    S/W CI/CD App.';
            app.LB_GuideText_2 = uilabel(app.Panel);
            app.LB_GuideText_2.Text = 'V1.0';
            app.S01_Panel_1 = uipanel(app.Panel);
            app.S01_LB_SetParam = uilabel(app.S01_Panel_1);
            app.S01_LB_SetParam.Text = 'Message';
            app.S01_BT_ImportDBFile = uibutton(app.S01_Panel_1, 'push');
            app.S01_BT_ImportDBFile.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_ImportDBFileButtonPushed, true);
            app.S01_BT_ImportDBFile.Text = '  Open Data Dictionary';
            app.S01_BT_ParamSet = uibutton(app.S01_Panel_1, 'push');
            app.S01_BT_ParamSet.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_ParamSetButtonPushed, true);
            app.S01_BT_ParamSet.Text = '  Prameter Settings';
            app.InterfaceandParameterSettingsLabel = uilabel(app.Panel);
            app.InterfaceandParameterSettingsLabel.Text = '■ Interface and Parameter Settings';
            app.S01_Panel_2 = uipanel(app.Panel);
            app.S01_BT_GenCod = uibutton(app.S01_Panel_2, 'push');
            app.S01_BT_GenCod.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_GenCodButtonPushed, true);
            app.S01_BT_GenCod.Text = 'Generation Gode';
            app.S01_LB_GenCode = uilabel(app.S01_Panel_2);
            app.S01_LB_GenCode.Text = 'Message';
            app.Label = uilabel(app.S01_Panel_2);
            app.Label.Text = '  ';
            app.S01_TreeSelCode = uitree(app.S01_Panel_2, 'checkbox');
            app.S01_TreeSelCode.ClickedFcn = createCallbackFcn(app, @S01_TreeSelCodeClicked, true);
            app.SelectSWCNode = uitreenode(app.S01_TreeSelCode);
            app.SelectSWCNode.Text = 'Select SWC';
            app.b01_OSnSchedulerNode = uitreenode(app.SelectSWCNode);
            app.b01_OSnSchedulerNode.Text = 'b01_OSnScheduler';
            app.c01_MainAdcNode = uitreenode(app.SelectSWCNode);
            app.c01_MainAdcNode.Text = 'c01_MainAdc';
            app.c02_SubAdcNode = uitreenode(app.SelectSWCNode);
            app.c02_SubAdcNode.Text = 'c02_SubAdc';
            app.d01_DiaglntegNode = uitreenode(app.SelectSWCNode);
            app.d01_DiaglntegNode.Text = 'd01_Diaglnteg';
            app.e01_MtrCtrl_VarGainNode = uitreenode(app.SelectSWCNode);
            app.e01_MtrCtrl_VarGainNode.Text = 'e01_MtrCtrl_VarGain';
            app.e02_MtrCtrl_PstNode = uitreenode(app.SelectSWCNode);
            app.e02_MtrCtrl_PstNode.Text = 'e02_MtrCtrl_Pst';
            app.e03_MtrCtrl_MtrCtrlNode = uitreenode(app.SelectSWCNode);
            app.e03_MtrCtrl_MtrCtrlNode.Text = 'e03_MtrCtrl_MtrCtrl';
            app.e04_MtrCtrl_PwmSetNode = uitreenode(app.SelectSWCNode);
            app.e04_MtrCtrl_PwmSetNode.Text = 'e04_MtrCtrl_PwmSet';
            app.f01_CommRx1Node = uitreenode(app.SelectSWCNode);
            app.f01_CommRx1Node.Text = 'f01_CommRx1';
            app.g03_CommTxNode = uitreenode(app.SelectSWCNode);
            app.g03_CommTxNode.Text = 'g03_CommTx';
            app.h01_MdeMgmtNode = uitreenode(app.SelectSWCNode);
            app.h01_MdeMgmtNode.Text = 'h01_MdeMgmt';
            app.j01_McuInitNode = uitreenode(app.SelectSWCNode);
            app.j01_McuInitNode.Text = 'j01_McuInit';
            app.SelectSWCLabel = uilabel(app.S01_Panel_2);
            app.SelectSWCLabel.Text = 'Select SWC';
            app.S01_BT_CompareCode1 = uibutton(app.S01_Panel_2, 'push');
            app.S01_BT_CompareCode1.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_CompareCode1ButtonPushed, true);
            app.S01_BT_CompareCode1.Text = '  Compare Code (Codegen VS Rlt)';
            app.S01_BT_OpnSlx = uibutton(app.S01_Panel_2, 'push');
            app.S01_BT_OpnSlx.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_OpnSlxButtonPushed, true);
            app.S01_BT_OpnSlx.Text = 'Open SWC .slx';
            app.AutoCodeGenerationLabel = uilabel(app.Panel);
            app.AutoCodeGenerationLabel.Text = '■ Auto Code Generation';
            app.SimulationParameterSettingsLabel = uilabel(app.Panel);
            app.SimulationParameterSettingsLabel.Text = '■ Simulation Parameter Settings';
            app.S01_Panel_3 = uipanel(app.Panel);
            app.S01_LB_SimSetParam = uilabel(app.S01_Panel_3);
            app.S01_LB_SimSetParam.Text = 'Message';
            app.S01_BT_SimParamSet = uibutton(app.S01_Panel_3, 'push');
            app.S01_BT_SimParamSet.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_SimParamSetButtonPushed, true);
            app.S01_BT_SimParamSet.Text = '  Simulation Parameter Settings';
            app.S01_BT_SimOpnMdl = uibutton(app.S01_Panel_3, 'push');
            app.S01_BT_SimOpnMdl.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_SimOpnMdlButtonPushed, true);
            app.S01_BT_SimOpnMdl.Text = {'Open'; 'Simulink Model'};
            app.S01_BT_SimSimMdl = uibutton(app.S01_Panel_3, 'push');
            app.S01_BT_SimSimMdl.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_SimSimMdlButtonPushed, true);
            app.S01_BT_SimSimMdl.Text = {'Sim'; 'Simulink Model'};
            app.InterfaceSettingsLabel = uilabel(app.Panel);
            app.InterfaceSettingsLabel.Text = '■ Interface Settings';
            app.S01_Panel_4 = uipanel(app.Panel);
            app.S01_LB_InterfaceSet = uilabel(app.S01_Panel_4);
            app.S01_LB_InterfaceSet.Text = 'Message';
            app.S01_BT_LoadInterface = uibutton(app.S01_Panel_4, 'push');
            app.S01_BT_LoadInterface.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_LoadInterfaceButtonPushed, true);
            app.S01_BT_LoadInterface.Text = '  Load Model Interface';
            app.S01_BT_UpdataInterface = uibutton(app.S01_Panel_4, 'push');
            app.S01_BT_UpdataInterface.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_UpdataInterfaceButtonPushed, true);
            app.S01_BT_UpdataInterface.Text = '  Update Model Interface';
            app.BuildProjectCCS1281Label = uilabel(app.Panel);
            app.BuildProjectCCS1281Label.Text = '■ Build Project (CCS12.8.1)';
            app.S01_Panel_6 = uipanel(app.Panel);
            app.S01_BT_BuildPrj = uibutton(app.S01_Panel_6, 'push');
            app.S01_BT_BuildPrj.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_BuildPrjButtonPushed, true);
            app.S01_BT_BuildPrj.Text = '  Start Build';
            app.TextAreaLabel = uilabel(app.S01_Panel_6);
            app.TextAreaLabel.Text = '';
            app.S01_TA_Build = uitextarea(app.S01_Panel_6);
            app.ConsoleLabel = uilabel(app.S01_Panel_6);
            app.ConsoleLabel.Text = 'Console';
            app.S01_BT_CcsOpen = uibutton(app.S01_Panel_6, 'push');
            app.S01_BT_CcsOpen.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_CcsOpenButtonPushed, true);
            app.S01_BT_CcsOpen.Text = '  CCS Open';
            app.GitVersionControlSystemLabel = uilabel(app.Panel);
            app.GitVersionControlSystemLabel.Text = '■ Git (Version Control System)';
            app.S01_Panel_5 = uipanel(app.Panel);
            app.S01_BT_OpnSourceTree = uibutton(app.S01_Panel_5, 'push');
            app.S01_BT_OpnSourceTree.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_OpnSourceTreeButtonPushed, true);
            app.S01_BT_OpnSourceTree.Text = '  Open Sourcetree';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MBD_CICD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end